classdef Nhomtruonglamhet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        gx_2                          matlab.ui.control.Label
        ptgx                          matlab.ui.control.EditField
        gx                            matlab.ui.control.Label
        fab                           matlab.ui.control.Label
        GiiButton                     matlab.ui.control.Button
        lap                           matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel           matlab.ui.control.Label
        kq                            matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        ChnphngphpgiiDropDown         matlab.ui.control.DropDown
        ChnphngphpgiiDropDownLabel    matlab.ui.control.Label
        ss                            matlab.ui.control.NumericEditField
        b                             matlab.ui.control.NumericEditField
        a                             matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel   matlab.ui.control.Label
        pt                            matlab.ui.control.EditField
        NhpphngtrnhLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        nskq                          matlab.ui.control.NumericEditField
        KtqunisuyLabel                matlab.ui.control.Label
        GiiButton_2                   matlab.ui.control.Button
        nskqpt                        matlab.ui.control.EditField
        NhpphngtrnhLabel_4            matlab.ui.control.Label
        nsgt                          matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel           matlab.ui.control.Label
        nsy                           matlab.ui.control.EditField
        NhpphngtrnhLabel_3            matlab.ui.control.Label
        nsx                           matlab.ui.control.EditField
        NhpphngtrnhLabel_2            matlab.ui.control.Label
        ChnphngnisuyDropDown          matlab.ui.control.DropDown
        ChnphngnisuyDropDownLabel     matlab.ui.control.Label
        gx_3                          matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        HiquyTab                      matlab.ui.container.Tab
        nskq_2                        matlab.ui.control.NumericEditField
        KtqunisuyLabel_2              matlab.ui.control.Label
        nskqpt_2                      matlab.ui.control.EditField
        NhpphngtrnhLabel_7            matlab.ui.control.Label
        GiiButton_3                   matlab.ui.control.Button
        nsgt_2                        matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_2         matlab.ui.control.Label
        nsy_2                         matlab.ui.control.EditField
        NhpphngtrnhLabel_6            matlab.ui.control.Label
        nsx_2                         matlab.ui.control.EditField
        NhpphngtrnhLabel_5            matlab.ui.control.Label
        ChnphngphphiquyDropDown       matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel  matlab.ui.control.Label
        gx_4                          matlab.ui.control.Label
        UIAxes2_2                     matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        GiiButton_4                   matlab.ui.control.Button
        dhketqua                      matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_6         matlab.ui.control.Label
        dhgiatri                      matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_5         matlab.ui.control.Label
        dhss                          matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_4         matlab.ui.control.Label
        ChnsaisDropDown               matlab.ui.control.DropDown
        ChnsaisDropDownLabel          matlab.ui.control.Label
        NhpdliuyLabel                 matlab.ui.control.Label
        NhphmLabel                    matlab.ui.control.Label
        NhpdliuxLabel                 matlab.ui.control.Label
        dhfx                          matlab.ui.control.EditField
        ChndnguvoDropDown             matlab.ui.control.DropDown
        ChndnguvoDropDownLabel        matlab.ui.control.Label
        dhh                           matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_3         matlab.ui.control.Label
        dhy                           matlab.ui.control.EditField
        dhx                           matlab.ui.control.EditField
        ChnphngphpohmDropDown         matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel    matlab.ui.control.Label
        gx_5                          matlab.ui.control.Label
        TchphnTab                     matlab.ui.container.Tab
        tpketqua                      matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_7         matlab.ui.control.Label
        GiiButton_5                   matlab.ui.control.Button
        fab_5                         matlab.ui.control.Label
        tpN                           matlab.ui.control.NumericEditField
        fab_4                         matlab.ui.control.Label
        fab_3                         matlab.ui.control.Label
        duoi                          matlab.ui.control.NumericEditField
        tren                          matlab.ui.control.NumericEditField
        fab_2                         matlab.ui.control.Label
        NhpdliuyLabel_2               matlab.ui.control.Label
        NhphmLabel_2                  matlab.ui.control.Label
        NhpdliuxLabel_2               matlab.ui.control.Label
        tpfx                          matlab.ui.control.EditField
        tpy                           matlab.ui.control.EditField
        tpx                           matlab.ui.control.EditField
        ChndnguvoDropDown_2           matlab.ui.control.DropDown
        ChndnguvoDropDown_2Label      matlab.ui.control.Label
        ChnphngphpohmDropDown_2       matlab.ui.control.DropDown
        ChnphngphpohmDropDown_2Label  matlab.ui.control.Label
        gx_6                          matlab.ui.control.Label
        DanhschnhmTab                 matlab.ui.container.Tab
        NhmMingitrnhmtrng100KLabel    matlab.ui.control.Label
        Lp22DTV_CLC2Label             matlab.ui.control.Label
        KhoaintVinthngLabel           matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Xemthanhvien                  matlab.ui.control.Button
        Thanhvien                     matlab.ui.container.ButtonGroup
        Ky                            matlab.ui.control.RadioButton
        Lac                           matlab.ui.control.RadioButton
        Khang                         matlab.ui.control.RadioButton
        Hoang                         matlab.ui.control.RadioButton
        Pha                           matlab.ui.control.RadioButton
        Nam                           matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChnphngphpgiiDropDown
        function ChnphngphpgiiDropDownValueChanged(app, event)

            switch app.ChnphngphpgiiDropDown.Value
                case 'Chia đôi'
                    app.b.Visible = 'on';
                    app.gx.Visible ='off';
                    app.ptgx.Visible = 'off';
                    app.fab.Text = 'Nhập khoảng nghiệm phân ly [a b]: ';
                case 'Lặp'
                    app.fab.Text = 'Nhập giá trị khởi tạo: ';
                    app.b.Visible = 'off';
                    app.gx.Text = 'Nhập hàm lặp g(x): ';
                    app.gx.Visible ='on';
                    app.ptgx.Visible ='on';
                case 'Newton'
                    app.fab.Text = 'Nhập giá trị khởi tạo: ';
                    app.b.Visible = 'off';
                    app.gx.Text = 'Đạo hàm của f(x): ';
                    app.gx.Visible ='on';
                    app.ptgx.Visible ='on';
                    
            end
            
        end

        % Button pushed function: GiiButton
        function GiiButtonPushed(app, event)
            select =0;
            switch app.ChnphngphpgiiDropDown.Value
                case 'Chia đôi'
                    select =1;
                case 'Lặp'
                    select =2;
                case 'Newton'
                    select =3;
            end
            fx = str2func(['@(x)' app.pt.Value]);
            saiso = app.ss.Value;
            if select ==1
                a = app.a.Value;
                b= app.b.Value;            
                [result, n] = chia_doi(fx, a, b, saiso);
                
            elseif select ==2
                x0 = app.a.Value;
                g =str2func(['@(x)' app.ptgx.Value]);
                [result, n] = lap(fx, g, x0, saiso);
            elseif select ==3
                x0 = app.a.Value;
                daoham = str2func(['@(x)' app.ptgx.Value]);
                [result, n] = newton(fx, daoham, x0, saiso);
            end
            app.kq.Value = result;
            app.lap.Value= n;
            fplot(app.UIAxes,fx, 'DisplayName', 'f(x)');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,result, fx(result), 'ro', 'DisplayName', 'Nghiệm gần đúng');
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'f(x)');
            title(app.UIAxes,'Đồ thị của phương trình f(x)');
            legend(app.UIAxes,'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            %close(1);
                    

        end

        % Value changed function: ChnphngnisuyDropDown
        function ChnphngnisuyDropDownValueChanged(app, event)
            value = app.ChnphngnisuyDropDown.Value;
            
        end

        % Button pushed function: GiiButton_2
        function GiiButton_2Pushed(app, event)
            select =0;
            switch app.ChnphngnisuyDropDown.Value
                case 'Newton'
                    select =1;
                case 'Lagrange'
                    select =2;
            end
            x_str = app.nsx.Value;
            x = str2num(x_str);
            y_str = app.nsy.Value;
            y = str2num(y_str);
            x0 = app.nsgt.Value;
            if select ==1
                [dathuc_noisuy, giatri_noisuy] = noisuy_newton(x, y, x0);
            elseif select ==2
                [dathuc_noisuy, giatri_noisuy] = noisuy_lagrange(x, y, x0);
            end
            app.nskqpt.Value= char(dathuc_noisuy);
            app.nskq.Value = double(giatri_noisuy);

            fplot(app.UIAxes2,dathuc_noisuy, [min(x), max(x)], 'DisplayName', 'Đa thức nội suy');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2,x, y, 'ro', 'DisplayName', 'Dữ liệu thực');
            plot(app.UIAxes2,x0, giatri_noisuy, 'bs', 'DisplayName', 'Giá trị nội suy');
            xlabel(app.UIAxes2,'x');
            ylabel(app.UIAxes2,'y');
            title(app.UIAxes2,'Đồ thị hàm số nội suy và dữ liệu thực');
            legend(app.UIAxes2,'show');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');
            
        end

        % Button pushed function: GiiButton_3
        function GiiButton_3Pushed(app, event)
           selehq =0;
            switch app.ChnphngphphiquyDropDown.Value
                case 'Tuyến tính'
                    selehq =1;
                case 'Hàm mũ'
                    selehq =2;
                case 'Logarit'
                    selehq =3;
            end
            x_str = app.nsx_2.Value;
            x = str2num(x_str);
            y_str = app.nsy_2.Value;
            y = str2num(y_str);
            x_predict = app.nsgt_2.Value;
            
            if selehq ==1
                [a1, a0, r2] = Regress(x, y);
                ya = a0 + a1*x;
            elseif selehq ==2
                X=log10(x);
                Y=log10(y);
                [A1, A0, r2] = Regress(X, Y);
                A=10^A0;
                B=A1;
                ya=A*x.^B;
            elseif selehq ==3
                X=log(x);
                [a1, a0, r2] = Regress(X, y);
                A=a0;
                B=a1;
                ya=A*x.^B;
            end
            app.nskqpt_2.Value = num2str(ya);
            app.nskq_2.Value =ya(x_predict)  ;
            plot(app.UIAxes2_2,x, y, 'o', 'DisplayName', 'Dữ liệu thực');
            hold(app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,x,ya, 'r-', 'DisplayName', 'Hàm hồi quy');
            plot(app.UIAxes2_2,x_predict,ya(x_predict),'bs', 'DisplayName', 'Dự đoán');
            xlabel(app.UIAxes2_2,'x');
            ylabel(app.UIAxes2_2,'y');
            title(app.UIAxes2_2,'Hồi quy tuyến tính và Dự đoán');
            legend(app.UIAxes2_2,'show');
            grid(app.UIAxes2_2,'on');
            hold(app.UIAxes2_2,'off');


        end

        % Value changed function: ChnphngphphiquyDropDown
        function ChnphngphphiquyDropDownValueChanged(app, event)
            value = app.ChnphngphphiquyDropDown.Value;
            
        end

        % Value changed function: ChndnguvoDropDown
        function ChndnguvoDropDownValueChanged(app, event)
            value = app.ChndnguvoDropDown.Value;
            switch value 
                case 'Dữ liệu x,y'
                    app.dhx.Visible ='on';
                    app.dhy.Visible ='on';
                    app.dhfx.Visible='off';
                    app.NhpdliuxLabel.Visible='on';
                    app.NhphmLabel.Visible ='off';
                    app.NhpdliuyLabel.Visible='on';
                case 'Hàm số'
                    app.dhx.Visible ='off';
                    app.dhy.Visible ='off';
                    app.dhfx.Visible='on';
                    app.NhpdliuxLabel.Visible='off';
                    app.NhphmLabel.Visible ='on';
                    app.NhpdliuyLabel.Visible='off';
            end
        end

        % Value changed function: ChnphngphpohmDropDown
        function ChnphngphpohmDropDownValueChanged(app, event)
            value = app.ChnphngphpohmDropDown.Value;
            
        end

        % Button pushed function: GiiButton_4
        function GiiButton_4Pushed(app, event)
            seledhpp =0;
            seledhinput =0;
            seleoh=0;
            switch app.ChnphngphpohmDropDown.Value
                case 'Xấp xỉ tiến'
                    seledhpp =1;
                case 'Xấp xỉ lùi'
                    seledhpp =2;
                case 'Xấp xỉ trung tâm'
                    seledhpp =3;
            end
            switch app.ChndnguvoDropDown.Value
                case 'Dữ liệu x,y'
                    seledhinput=1;
                case 'Hàm số'
                    seledhinput =2;
            end
            switch app.ChnsaisDropDown.Value
                case 'O(h)'
                    seleoh =1;
                case 'O(h^2)'
                    seleoh =2;
            end
            x_dhstr = app.dhx.Value;
            y_dhstr =app.dhy.Value;
            x_data = str2num(x_dhstr);
            y_data = str2num(y_dhstr);
            h = app.dhh.Value;
            order = app.dhss.Value;
            x_value = app.dhgiatri.Value;
            f = str2func(['@(x)' app.dhfx.Value]);

            if seledhpp ==1
                if seledhinput ==1
                    if seleoh ==1
                        resultdh = daohamtien_Oh(x_data, y_data, h, order, x_value);
                    elseif seleoh ==2
                        resultdh = daohamtien_Oh2(x_data, y_data, h, order, x_value);
                    end
                elseif seledhinput==2
                    if seleoh ==1
                        resultdh = hamdaohamtien_Ohfx(f, h, order, x_value);
                    elseif seleoh ==2
                        resultdh = hamdaohamtien_Oh2fx(f, h, order, x_value);
                    end
                end
            elseif seledhpp ==2
                if seledhinput ==1
                    if seleoh ==1
                        resultdh = daohamlui_Oh( x_data, y_data, h, x_value);
                    elseif seleoh ==2
                        resultdh = daohamlui_Oh2( x_data, y_data, h, x_value);
                    end
                elseif seledhinput ==2
                    if seleoh ==1
                        resultdh = hamdaohamlui_Ohfx(f, h, x_value);
                    elseif seleoh ==2
                        resultdh = hamdaohamlui_Oh2fx(f, h, x_value);
                    end
                end
            elseif seledhpp ==3
                if seledhinput ==1
                    if seleoh ==1
                        resultdh = daohamtrungtam_Oh(x_data, y_data, h, x_value);
                    elseif seleoh ==2
                        resultdh = daohamtrungtam_Oh2(x_data, y_data, h, x_value);
                    end
                elseif seledhinput ==2
                    if seleoh ==1
                        resultdh = hamdaohamtrungtam_Ohfx(f, h, x_value);
                    elseif seleoh ==2
                        resultdh = hamdaohamtrungtam_Oh2fx(f, h, x_value);
                    end
                end
            end
            app.dhketqua.Value = resultdh;


        end

        % Value changed function: ChnsaisDropDown
        function ChnsaisDropDownValueChanged(app, event)

            
        end

        % Button pushed function: GiiButton_5
        function GiiButton_5Pushed(app, event)
            seletppp =0;
            seletpinput =0;
            switch app.ChnphngphpohmDropDown_2.Value
                case 'Hình thang'
                    seletppp =1;
                case 'Simpson 1/3'
                    seletppp=2;
                case 'Simpson 3/8'
                    seletppp=3;
            end
            switch app.ChndnguvoDropDown_2.Value
                case 'Dữ liệu x,y'
                    seletpinput =1;
                case 'Hàm số'
                    seletpinput=2;
            end
            x_tpstr = app.tpx.Value;
            y_tpstr = app.tpy.Value;
            x = str2num(x_tpstr);
            y = str2num(y_tpstr);
            tpa = app.duoi.Value;
            tpb = app.tren.Value;
            N = app.tpN.Value;
            %fx = str2func(['@(x)' app.tpfx.Value]);
            if seletppp ==1
                if seletpinput ==1
                    restp= tichphan_hinhthang(x, y, tpa, tpb, N);
                elseif seletpinput ==2
                    fx = str2func(['@(x)' app.tpfx.Value]);
                    restp= hamtichphan_hinhthangfx(fx, tpa, tpb, N);
                end
            elseif seletppp ==2
                if seletpinput ==1
                    restp=  tichphan_simpson13(x, y, tpa, tpb, N);
                elseif seletpinput ==2
                    fx = str2func(['@(x)' app.tpfx.Value]);
                    restp= tichphan_simpson13fx(fx, tpa, tpb, N);
                end  
            elseif seletppp==3
                if seletpinput ==1
                    restp=  tichphan_simpson38(x, y, tpa, tpb, N);
                elseif seletpinput ==2
                    fx = str2func(['@(x)' app.tpfx.Value]);
                    restp= tichphan_simpson38fx(fx, tpa, tpb, N);
                end 
            end
            app.tpketqua.Value =restp;


        end

        % Value changed function: ChnphngphpohmDropDown_2
        function ChnphngphpohmDropDown_2ValueChanged(app, event)
            value = app.ChnphngphpohmDropDown_2.Value;
            
        end

        % Value changed function: ChndnguvoDropDown_2
        function ChndnguvoDropDown_2ValueChanged(app, event)
            value = app.ChndnguvoDropDown_2.Value;
            switch value
                case 'Dữ liệu x,y'
                    app.tpx.Visible ='on';
                    app.tpy.Visible ='on';
                    app.tpfx.Visible='off';
                    app.NhpdliuxLabel_2.Visible='on';
                    app.NhphmLabel_2.Visible ='off';
                    app.NhpdliuyLabel_2.Visible='on';
                    %app.tpfx.Enable = 'off';
                case 'Hàm số'
                    app.tpx.Visible ='off';
                    app.tpy.Visible ='off';
                    app.tpfx.Visible='on';
                    app.NhpdliuxLabel_2.Visible='off';
                    app.NhphmLabel_2.Visible ='on';
                    app.NhpdliuyLabel_2.Visible='off';
                    %app.tpfx.Enable = 'on';
            end
            
        end

        % Button down function: Thanhvien
        function ThanhvienButtonDown(app, event)
            
        end

        % Button pushed function: Xemthanhvien
        function XemthanhvienButtonPushed(app, event)
            % Lấy giá trị của radio button được chọn
                selectedMember = app.Thanhvien.SelectedObject;
                
                % Kiểm tra xem radio button nào được chọn và cập nhật UITable
                if selectedMember == app.Ky
                    data = {'Phạm Văn Ký', '22207053', 'Thành viên', 'Tab nghiệm', '100%'};
                elseif selectedMember == app.Lac
                    data = {'Trần Gia Lạc', '22207054', 'Thành viên', 'Tab hồi quy', '100%'};
                elseif selectedMember == app.Hoang
                    data = {'Vũ Việt Hoàng', '22207031', 'Thành viên', 'Tab nội suy', '100%'};
                elseif selectedMember == app.Khang
                    data = {'Mai Xuân Khang', '22207043', 'Thành viên', 'Tab đạo hàm', '100%'};
                elseif selectedMember == app.Pha
                    data = {'Lê Tấn Phi Pha', '22207066', 'Thành viên', 'Tab nghiệm', '100%'};
                elseif selectedMember == app.Nam
                    data = {'Văn Đình Nam', '22207063', 'Nhóm trưởng', 'Tab tích phân và Tab danh sách nhóm ', '100%'};
                else
                    % Nếu không có radio button nào được chọn, không cập nhật UITable
                    return;
                end
                
                % Hiển thị dữ liệu trong UITable
                app.UITable.Data = data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình f(x): ';
            app.pt = uieditfield(app.NghimTab, 'text');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép: ';
            app.a = uieditfield(app.NghimTab, 'numeric');
            app.b = uieditfield(app.NghimTab, 'numeric');
            app.ss = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphpgiiDropDownLabel = uilabel(app.NghimTab);
            app.ChnphngphpgiiDropDownLabel.Text = 'Chọn phương pháp giải';
            app.ChnphngphpgiiDropDown = uidropdown(app.NghimTab);
            app.ChnphngphpgiiDropDown.Items = {'...', 'Chia đôi', 'Lặp', 'Newton'};
            app.ChnphngphpgiiDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngphpgiiDropDownValueChanged, true);
            app.ChnphngphpgiiDropDown.Value = '...';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm: ';
            app.kq = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.lap = uieditfield(app.NghimTab, 'numeric');
            app.GiiButton = uibutton(app.NghimTab, 'push');
            app.GiiButton.ButtonPushedFcn = createCallbackFcn(app, @GiiButtonPushed, true);
            app.GiiButton.Text = 'Giải';
            app.fab = uilabel(app.NghimTab);
            app.fab.Text = 'Nhập khoảng nghiệm phân ly [a b]: ';
            app.gx = uilabel(app.NghimTab);
            app.gx.Text = 'Nhập hàm lặp g(x): ';
            app.ptgx = uieditfield(app.NghimTab, 'text');
            app.gx_2 = uilabel(app.NghimTab);
            app.gx_2.Text = 'GIẢI PHƯƠNG TRÌNH';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.gx_3 = uilabel(app.NisuyTab);
            app.gx_3.Text = 'NỘI SUY DỮ LIỆU';
            app.ChnphngnisuyDropDownLabel = uilabel(app.NisuyTab);
            app.ChnphngnisuyDropDownLabel.Text = 'Chọn phương nội suy';
            app.ChnphngnisuyDropDown = uidropdown(app.NisuyTab);
            app.ChnphngnisuyDropDown.Items = {'Newton', 'Lagrange'};
            app.ChnphngnisuyDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngnisuyDropDownValueChanged, true);
            app.ChnphngnisuyDropDown.Value = 'Newton';
            app.NhpphngtrnhLabel_2 = uilabel(app.NisuyTab);
            app.NhpphngtrnhLabel_2.Text = 'Nhập dữ liệu x: ';
            app.nsx = uieditfield(app.NisuyTab, 'text');
            app.NhpphngtrnhLabel_3 = uilabel(app.NisuyTab);
            app.NhpphngtrnhLabel_3.Text = 'Nhập dữ liệu y: ';
            app.nsy = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy: ';
            app.nsgt = uieditfield(app.NisuyTab, 'numeric');
            app.NhpphngtrnhLabel_4 = uilabel(app.NisuyTab);
            app.NhpphngtrnhLabel_4.Text = 'Kết quả đa thức nội suy: ';
            app.nskqpt = uieditfield(app.NisuyTab, 'text');
            app.GiiButton_2 = uibutton(app.NisuyTab, 'push');
            app.GiiButton_2.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_2Pushed, true);
            app.GiiButton_2.Text = 'Giải';
            app.KtqunisuyLabel = uilabel(app.NisuyTab);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy ';
            app.nskq = uieditfield(app.NisuyTab, 'numeric');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes2_2 = uiaxes(app.HiquyTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.gx_4 = uilabel(app.HiquyTab);
            app.gx_4.Text = 'HỒI QUY DỮ LIỆU';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChnphngphphiquyDropDown = uidropdown(app.HiquyTab);
            app.ChnphngphphiquyDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChnphngphphiquyDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngphphiquyDropDownValueChanged, true);
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính';
            app.NhpphngtrnhLabel_5 = uilabel(app.HiquyTab);
            app.NhpphngtrnhLabel_5.Text = 'Nhập dữ liệu x: ';
            app.nsx_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpphngtrnhLabel_6 = uilabel(app.HiquyTab);
            app.NhpphngtrnhLabel_6.Text = 'Nhập dữ liệu y: ';
            app.nsy_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcnnisuyLabel_2 = uilabel(app.HiquyTab);
            app.NhpgitrcnnisuyLabel_2.Text = 'Nhập giá trị cần dự đoán: ';
            app.nsgt_2 = uieditfield(app.HiquyTab, 'numeric');
            app.GiiButton_3 = uibutton(app.HiquyTab, 'push');
            app.GiiButton_3.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_3Pushed, true);
            app.GiiButton_3.Text = 'Giải';
            app.NhpphngtrnhLabel_7 = uilabel(app.HiquyTab);
            app.NhpphngtrnhLabel_7.Text = 'Kết quả phương trình hồi quy: ';
            app.nskqpt_2 = uieditfield(app.HiquyTab, 'text');
            app.KtqunisuyLabel_2 = uilabel(app.HiquyTab);
            app.KtqunisuyLabel_2.Text = 'Kết quả dự đoán ';
            app.nskq_2 = uieditfield(app.HiquyTab, 'numeric');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.gx_5 = uilabel(app.ohmTab);
            app.gx_5.Text = 'ĐẠO HÀM';
            app.ChnphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChnphngphpohmDropDown = uidropdown(app.ohmTab);
            app.ChnphngphpohmDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ChnphngphpohmDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngphpohmDropDownValueChanged, true);
            app.ChnphngphpohmDropDown.Value = 'Xấp xỉ tiến';
            app.dhx = uieditfield(app.ohmTab, 'text');
            app.dhy = uieditfield(app.ohmTab, 'text');
            app.NhpgitrcnnisuyLabel_3 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_3.Text = 'Nhập giá trị bước h: ';
            app.dhh = uieditfield(app.ohmTab, 'numeric');
            app.ChndnguvoDropDownLabel = uilabel(app.ohmTab);
            app.ChndnguvoDropDownLabel.Text = 'Chọn dạng đầu vào';
            app.ChndnguvoDropDown = uidropdown(app.ohmTab);
            app.ChndnguvoDropDown.Items = {'...', 'Dữ liệu x,y', 'Hàm số'};
            app.ChndnguvoDropDown.ValueChangedFcn = createCallbackFcn(app, @ChndnguvoDropDownValueChanged, true);
            app.ChndnguvoDropDown.Value = '...';
            app.dhfx = uieditfield(app.ohmTab, 'text');
            app.NhpdliuxLabel = uilabel(app.ohmTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x: ';
            app.NhphmLabel = uilabel(app.ohmTab);
            app.NhphmLabel.Text = 'Nhập hàm: ';
            app.NhpdliuyLabel = uilabel(app.ohmTab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y: ';
            app.ChnsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChnsaisDropDownLabel.Text = 'Chọn sai số';
            app.ChnsaisDropDown = uidropdown(app.ohmTab);
            app.ChnsaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.ChnsaisDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnsaisDropDownValueChanged, true);
            app.ChnsaisDropDown.Value = 'O(h)';
            app.NhpgitrcnnisuyLabel_4 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_4.Text = 'Nhập giá trị sai số: ';
            app.dhss = uieditfield(app.ohmTab, 'numeric');
            app.NhpgitrcnnisuyLabel_5 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_5.Text = 'Nhập giá trị cần tính đạo hàm: ';
            app.dhgiatri = uieditfield(app.ohmTab, 'numeric');
            app.NhpgitrcnnisuyLabel_6 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_6.Text = 'Kết quả đạo hàm: ';
            app.dhketqua = uieditfield(app.ohmTab, 'numeric');
            app.GiiButton_4 = uibutton(app.ohmTab, 'push');
            app.GiiButton_4.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_4Pushed, true);
            app.GiiButton_4.Text = 'Giải';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.gx_6 = uilabel(app.TchphnTab);
            app.gx_6.Text = 'TÍCH PHÂN';
            app.ChnphngphpohmDropDown_2Label = uilabel(app.TchphnTab);
            app.ChnphngphpohmDropDown_2Label.Text = 'Chọn phương pháp đạo hàm';
            app.ChnphngphpohmDropDown_2 = uidropdown(app.TchphnTab);
            app.ChnphngphpohmDropDown_2.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChnphngphpohmDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChnphngphpohmDropDown_2ValueChanged, true);
            app.ChnphngphpohmDropDown_2.Value = 'Hình thang';
            app.ChndnguvoDropDown_2Label = uilabel(app.TchphnTab);
            app.ChndnguvoDropDown_2Label.Text = 'Chọn dạng đầu vào';
            app.ChndnguvoDropDown_2 = uidropdown(app.TchphnTab);
            app.ChndnguvoDropDown_2.Items = {'...', 'Dữ liệu x,y', 'Hàm số'};
            app.ChndnguvoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChndnguvoDropDown_2ValueChanged, true);
            app.ChndnguvoDropDown_2.Value = '...';
            app.tpx = uieditfield(app.TchphnTab, 'text');
            app.tpy = uieditfield(app.TchphnTab, 'text');
            app.tpfx = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuxLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuxLabel_2.Text = 'Nhập dữ liệu x: ';
            app.NhphmLabel_2 = uilabel(app.TchphnTab);
            app.NhphmLabel_2.Text = 'Nhập hàm: ';
            app.NhpdliuyLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuyLabel_2.Text = 'Nhập dữ liệu y: ';
            app.fab_2 = uilabel(app.TchphnTab);
            app.fab_2.Text = 'Nhập cận tính tích phân:  ';
            app.tren = uieditfield(app.TchphnTab, 'numeric');
            app.duoi = uieditfield(app.TchphnTab, 'numeric');
            app.fab_3 = uilabel(app.TchphnTab);
            app.fab_3.Text = 'Cận trên:';
            app.fab_4 = uilabel(app.TchphnTab);
            app.fab_4.Text = 'Cận dưới:';
            app.tpN = uieditfield(app.TchphnTab, 'numeric');
            app.fab_5 = uilabel(app.TchphnTab);
            app.fab_5.Text = 'Nhập N';
            app.GiiButton_5 = uibutton(app.TchphnTab, 'push');
            app.GiiButton_5.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_5Pushed, true);
            app.GiiButton_5.Text = 'Giải';
            app.NhpgitrcnnisuyLabel_7 = uilabel(app.TchphnTab);
            app.NhpgitrcnnisuyLabel_7.Text = 'Kết quả tích phân: ';
            app.tpketqua = uieditfield(app.TchphnTab, 'numeric');
            app.DanhschnhmTab = uitab(app.TabGroup);
            app.DanhschnhmTab.Title = 'Giới thiệu nhóm';
            app.Thanhvien = uibuttongroup(app.DanhschnhmTab);
            app.Thanhvien.Title = 'Thành viên';
            app.Thanhvien.ButtonDownFcn = createCallbackFcn(app, @ThanhvienButtonDown, true);
            app.Nam = uiradiobutton(app.Thanhvien);
            app.Nam.Text = 'Văn Đình Nam';
            app.Nam.Value = true;
            app.Pha = uiradiobutton(app.Thanhvien);
            app.Pha.Text = 'Lê Tấn Phi Pha';
            app.Hoang = uiradiobutton(app.Thanhvien);
            app.Hoang.Text = 'Vũ Việt Hoàng';
            app.Khang = uiradiobutton(app.Thanhvien);
            app.Khang.Text = 'Mai Xuân Khang';
            app.Lac = uiradiobutton(app.Thanhvien);
            app.Lac.Text = 'Trần Gia Lạc';
            app.Ky = uiradiobutton(app.Thanhvien);
            app.Ky.Text = 'Phạm Văn Ký';
            app.Xemthanhvien = uibutton(app.DanhschnhmTab, 'push');
            app.Xemthanhvien.ButtonPushedFcn = createCallbackFcn(app, @XemthanhvienButtonPushed, true);
            app.Xemthanhvien.Text = 'Xem thành viên';
            app.UITable = uitable(app.DanhschnhmTab);
            app.UITable.ColumnName = {'Họ và tên'; 'MSSV'; 'Chức vụ'; 'Nhiệm vụ'; 'Mức độ hoàn thành'};
            app.UITable.RowName = {};
            app.KhoaintVinthngLabel = uilabel(app.DanhschnhmTab);
            app.KhoaintVinthngLabel.Text = 'Khoa Điện tử - Viễn thông';
            app.Lp22DTV_CLC2Label = uilabel(app.DanhschnhmTab);
            app.Lp22DTV_CLC2Label.Text = 'Lớp: 22DTV_CLC2';
            app.NhmMingitrnhmtrng100KLabel = uilabel(app.DanhschnhmTab);
            app.NhmMingitrnhmtrng100KLabel.Text = 'Nhóm: Mỗi người trả nhóm trưởng 100K';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Nhomtruonglamhet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end